classdef CONVERTER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        COORDINATESCONVERTERPanel  matlab.ui.container.Panel
        NEditField                 matlab.ui.control.NumericEditField
        NEditFieldLabel            matlab.ui.control.Label
        HEIGHTZEditField_2         matlab.ui.control.NumericEditField
        HEIGHTZEditField_2Label    matlab.ui.control.Label
        EASTINGEditField           matlab.ui.control.NumericEditField
        EASTINGEditFieldLabel      matlab.ui.control.Label
        NORTHINGEditField          matlab.ui.control.NumericEditField
        NORTHINGEditFieldLabel     matlab.ui.control.Label
        HEIGHTHEditField           matlab.ui.control.NumericEditField
        HEIGHTHEditFieldLabel      matlab.ui.control.Label
        LONGITUDEYEditField        matlab.ui.control.NumericEditField
        LONGITUDEYEditFieldLabel   matlab.ui.control.Label
        LATITUDEXEditField         matlab.ui.control.NumericEditField
        LATITUDEXEditFieldLabel    matlab.ui.control.Label
        CONVERTButton              matlab.ui.control.StateButton
        CLEARButton                matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CONVERTButton
        function CONVERTButtonValueChanged(app, event)
            value = app.CONVERTButton.Value;
            a= 6378137;
            b= 6356752.314245;
            e= 0.0818191913;
            d= ((e)^2);
            k= 1-(d*((sind(app.LATITUDEXEditField.Value))^2));
            v= sqrt(k);
            N= a/v;
            app.NEditField.Value= N;
            X= (N+app.HEIGHTHEditField.Value)*cosd(app.LATITUDEXEditField.Value)*cosd(app.LONGITUDEYEditField.Value);
            Y= (N+app.HEIGHTHEditField.Value)*cosd(app.LATITUDEXEditField.Value)*sind(app.LONGITUDEYEditField.Value);
            Z= ((N*(1-(e)^2))+app.HEIGHTHEditField.Value)*sind(app.LATITUDEXEditField.Value);
            app.NORTHINGEditField.Value = X;
            app.EASTINGEditField.Value= Y;
            app.HEIGHTZEditField_2.Value= Z;


        end

        % Value changed function: CLEARButton
        function CLEARButtonValueChanged(app, event)
            value = app.CLEARButton.Value;
            app.LATITUDEXEditField.Value= clc;
            app.LONGITUDEYEditField.Value= clc;
            app.HEIGHTHEditField.value= clc;
            app.NORTHINGEditField.Value= clc;
            app.EASTINGEditField.Value= clc;
            app.HEIGHTZEditField_2.Value= clc;
            app.NEditField.Value= clc;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.COORDINATESCONVERTERPanel = uipanel(app.UIFigure);
            app.COORDINATESCONVERTERPanel.Title = 'COORDINATES CONVERTER';
            app.CLEARButton = uibutton(app.COORDINATESCONVERTERPanel, 'state');
            app.CLEARButton.ValueChangedFcn = createCallbackFcn(app, @CLEARButtonValueChanged, true);
            app.CLEARButton.Text = 'CLEAR';
            app.CONVERTButton = uibutton(app.COORDINATESCONVERTERPanel, 'state');
            app.CONVERTButton.ValueChangedFcn = createCallbackFcn(app, @CONVERTButtonValueChanged, true);
            app.CONVERTButton.Text = 'CONVERT';
            app.LATITUDEXEditFieldLabel = uilabel(app.COORDINATESCONVERTERPanel);
            app.LATITUDEXEditFieldLabel.Text = {'LATITUDE (X)'; ''};
            app.LATITUDEXEditField = uieditfield(app.COORDINATESCONVERTERPanel, 'numeric');
            app.LATITUDEXEditField.ValueDisplayFormat = '%.3f';
            app.LONGITUDEYEditFieldLabel = uilabel(app.COORDINATESCONVERTERPanel);
            app.LONGITUDEYEditFieldLabel.Text = 'LONGITUDE (Y)';
            app.LONGITUDEYEditField = uieditfield(app.COORDINATESCONVERTERPanel, 'numeric');
            app.LONGITUDEYEditField.ValueDisplayFormat = '%.3f';
            app.HEIGHTHEditFieldLabel = uilabel(app.COORDINATESCONVERTERPanel);
            app.HEIGHTHEditFieldLabel.Text = 'HEIGHT (H)';
            app.HEIGHTHEditField = uieditfield(app.COORDINATESCONVERTERPanel, 'numeric');
            app.HEIGHTHEditField.ValueDisplayFormat = '%.3f';
            app.NORTHINGEditFieldLabel = uilabel(app.COORDINATESCONVERTERPanel);
            app.NORTHINGEditFieldLabel.Text = 'NORTHING';
            app.NORTHINGEditField = uieditfield(app.COORDINATESCONVERTERPanel, 'numeric');
            app.NORTHINGEditField.ValueDisplayFormat = '%.3f';
            app.EASTINGEditFieldLabel = uilabel(app.COORDINATESCONVERTERPanel);
            app.EASTINGEditFieldLabel.Text = 'EASTING';
            app.EASTINGEditField = uieditfield(app.COORDINATESCONVERTERPanel, 'numeric');
            app.EASTINGEditField.ValueDisplayFormat = '%.3f';
            app.HEIGHTZEditField_2Label = uilabel(app.COORDINATESCONVERTERPanel);
            app.HEIGHTZEditField_2Label.Text = 'HEIGHT (Z)';
            app.HEIGHTZEditField_2 = uieditfield(app.COORDINATESCONVERTERPanel, 'numeric');
            app.HEIGHTZEditField_2.ValueDisplayFormat = '%.3f';
            app.NEditFieldLabel = uilabel(app.COORDINATESCONVERTERPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.COORDINATESCONVERTERPanel, 'numeric');
            app.NEditField.ValueDisplayFormat = '%.3f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONVERTER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end